classdef projection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Slider                   matlab.ui.control.Slider
        CalibPanel               matlab.ui.container.Panel
        CalibPanelGrid           matlab.ui.container.GridLayout
        CalibCalculate           matlab.ui.control.Button
        CalibShow                matlab.ui.control.Button
        CalibSave                matlab.ui.control.Button
        CalibYLen                matlab.ui.control.NumericEditField
        YlengthmmEditFieldLabel  matlab.ui.control.Label
        CalibXLen                matlab.ui.control.NumericEditField
        XlengthmmEditFieldLabel  matlab.ui.control.Label
        CalibYDir                matlab.ui.control.DropDown
        YdirectionDropDownLabel  matlab.ui.control.Label
        CalibXDir                matlab.ui.control.DropDown
        XdirectionDropDownLabel  matlab.ui.control.Label
        CalibOrigin              matlab.ui.control.DropDown
        OriginLabel              matlab.ui.control.Label
        CalibInitButton          matlab.ui.control.Button
        CalibType                matlab.ui.control.DropDown
        TypeDropDownLabel        matlab.ui.control.Label
        VideoPanel               matlab.ui.container.Panel
        VideoPanelGrid           matlab.ui.container.GridLayout
        VideoFPSField            matlab.ui.control.NumericEditField
        FPSEditFieldLabel        matlab.ui.control.Label
        VideoHeightField         matlab.ui.control.NumericEditField
        HeightEditFieldLabel     matlab.ui.control.Label
        VideoWidthField          matlab.ui.control.NumericEditField
        WidthEditFieldLabel      matlab.ui.control.Label
        VideoFileLabel           matlab.ui.control.Label
        VideoOpenButton          matlab.ui.control.Button
        ImageAxes                matlab.ui.control.UIAxes
    end

    
    properties (SetAccess = protected, GetAccess = public)
        VideoFilename           % Filename of the currently loaded file.
        VideoLocation           % Directory of file.

        TForm                   % The resulting transformation.

        Image                   % Currently display image.
    end

    properties(Access = private)
        VideoHandle             % Video reader handle.

        Points                  % Points displayed over video.
        Labels                  % Associated labels.

        SelectedPoint = []      % Point selected with mouse button down.
    end

    methods(Hidden)

        function initCalibration(app)
            % fitgeotform2d: "projective"
            x = app.ImageAxes.XLim;
            y = app.ImageAxes.YLim;

            app.Points = line(app.ImageAxes,...
                [x(1)+99  x(2)-100 x(2)-100 x(1)+99  x(1)+99 ], ...
                [y(2)-100 y(2)-100 y(1)+99  y(1)+99  y(2)-100], ...
                LineWidth = 2, ...
                Marker = 'o', MarkerSize = 24, ...
                Color = 'b');

            app.Labels = cell(1,4);
            lbl = {'A','B','C','D'};
            for i = 1:4
                app.Labels{i} = text(app.ImageAxes, 1, 1, lbl{i},...
                    FontSize = 24, Color = 'b', HorizontalAlignment='center');
                app.updateLabel(i, [app.Points.XData(i),app.Points.YData(i)]);
            end
        end

        function updateLabel(app,idx,~)
            N = numel(app.Points.XData);
            p = [app.Points.XData(idx(1)); app.Points.YData(idx(1))];
            if idx == 1
                p_prev = [app.Points.XData(N); app.Points.YData(N)];
            else
                p_prev = [app.Points.XData(idx(1)-1); app.Points.YData(idx(1)-1)];
            end
            p_next = [app.Points.XData(idx(1)+1); app.Points.YData(idx(1)+1)];

            c = (p_prev + p_next)/2;
            c = 36 * (c-p)/norm(c-p);

            app.Labels{idx(1)}.Position = p + c;
        end

        function trackMouseCalibration(app,pos,btn)
            persistent inprogress

            if isempty(app.Points)
                return
            end

            if isempty(inprogress)
                inprogress = true;
            else
                return
            end

            markers = [app.Points.XData(1:4);app.Points.YData(1:4)]';
            idx = find(sum((pos-markers).^2,2) <= 24^2); 

            if isempty(btn)
                if ~isempty(app.SelectedPoint)
                    app.updateLabel(idx,pos);
                    app.SelectedPoint = [];
                end

                if isempty(idx)
                    app.Points.Color = 'b';
                else
                    app.Points.Color = 'r';
                end
            else
                switch btn
                    case 'normal'
                        if ~isempty(idx) && isempty(app.SelectedPoint)
                            if idx == 1
                                app.SelectedPoint = [1,5];
                            else
                                app.SelectedPoint = idx;
                            end
                        end

                        if ~isempty(app.SelectedPoint)
                            app.Points.XData(app.SelectedPoint) = pos(1);
                            app.Points.YData(app.SelectedPoint) = pos(2);
                        end                            

                    otherwise
                        % ignored
                end
            end

            inprogress = [];
        end

    end
    
    methods
        function showFrame(app,idx)
            arguments
                app ana.camera.pm.projection
                idx {mustBeNumeric} = []
            end

            if isempty(idx)
                app.Image = app.VideoHandle.readFrame();
            else
                app.Image = app.VideoHandle.read(idx);
            end

            img = findobj(app.ImageAxes, type='Image');
            if isempty(img)
                imagesc(app.ImageAxes,app.Image);
            else
                img.CData = app.Image;
            end
            xlim(app.ImageAxes,[1 app.VideoHandle.Width]);
            ylim(app.ImageAxes,[1 app.VideoHandle.Height]);

            app.Slider.Value = (idx-1) / app.VideoHandle.FrameRate;
        end

        function res = isOverImage(app,pos)
            res = pos(1) >= app.ImageAxes.XLim(1)-12 && ...
                pos(1) <= app.ImageAxes.XLim(2)+12 && ...
                pos(2) >= app.ImageAxes.YLim(1)-12 && ...
                pos(2) <= app.ImageAxes.YLim(2)+12;
        end

    end

    properties (Access = private)
        MouseButtonPressed = false;
        MouseButtonSelection = [];
    end

    methods
        function trackMouse(app,pos,sel)
            if ~isempty(app.Image)
                app.trackMouseCalibration(pos,sel);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VideoOpenButton
        function VideoOpenButtonPushed(app, event)
            [file,location] = uigetfile({'*.mp4'}, "Select video file...");

            if isnumeric(file)
                return
            end

            app.VideoFilename = file;
            app.VideoLocation = location;
            app.VideoHandle = VideoReader(fullfile(location,file));

            % clear old fields
            app.Points = [];
            app.Labels = [];

            % update UI
            cla(app.ImageAxes);
            app.VideoFileLabel.Text = 'scanning file...';
            app.VideoWidthField.Value = app.VideoHandle.Width;
            app.VideoHeightField.Value = app.VideoHandle.Height;
            app.VideoFPSField.Value = app.VideoHandle.FrameRate;
            app.Slider.Limits = [0 app.VideoHandle.Duration];
            app.Slider.Value = 0;
            app.showFrame(1);
            app.VideoFileLabel.Text = file;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            while ~app.Slider.Enable
                pause(0.05);
            end

            app.Slider.Enable = false;
            app.showFrame(floor(app.Slider.Value * app.VideoHandle.FrameRate)+1);
            app.Slider.Enable = true;
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            axpos = app.ImageAxes.CurrentPoint(1, 1:2);
            if app.isOverImage(axpos)
                app.trackMouse(axpos, app.MouseButtonSelection);
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            axpos = app.ImageAxes.CurrentPoint(1, 1:2);
            if app.isOverImage(axpos)
                app.MouseButtonPressed = true;
                app.MouseButtonSelection = app.UIFigure.SelectionType;
                app.trackMouse(axpos,app.MouseButtonSelection);
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            axpos = app.ImageAxes.CurrentPoint(1, 1:2);
            if app.isOverImage(axpos)
                app.MouseButtonPressed = false;
                app.MouseButtonSelection = [];
                app.trackMouse(axpos,[]);
            end
        end

        % Value changed function: CalibType
        function CalibTypeValueChanged(app, event)
            % unused
        end

        % Button pushed function: CalibInitButton
        function CalibInitButtonPushed(app, event)
            cla(app.ImageAxes);
            app.showFrame(floor(app.Slider.Value * app.VideoHandle.FrameRate)+1);
            app.initCalibration();
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            axpos = app.ImageAxes.CurrentPoint(1, 1:2);
            if app.isOverImage(axpos)
                % verticalScrollAmount = event.VerticalScrollAmount;
                cnt = event.VerticalScrollCount;


                %xlim(app.ImageAxes,[1 app.VideoHandle.Width]);
                %ylim(app.ImageAxes,[1 app.VideoHandle.Height]);

                scl = 1.2^cnt;
                    
                lim_x = axpos(1) + scl*(app.ImageAxes.XLim - axpos(1));
                if lim_x(1) < 1
                    lim_x(1) = 1;
                end
                if lim_x(2) > app.VideoHandle.Width
                    lim_x(2) = app.VideoHandle.Width;
                end
                app.ImageAxes.XLim = lim_x;

                lim_y = axpos(2) + scl*(app.ImageAxes.YLim - axpos(2));
                if lim_y(1) < 1
                    lim_y(1) = 1;
                end
                if lim_y(2) > app.VideoHandle.Height
                    lim_y(2) = app.VideoHandle.Height;
                end
                app.ImageAxes.YLim = lim_y;
            end            
        end

        % Button pushed function: CalibSave
        function CalibSaveButtonPushed(app, event)
            [~,basename,~] = fileparts(app.VideoFilename);
            [file,location] = uiputfile('*.mat', 'Save projective transformation...', [basename '-tform.mat']);

            if isnumeric(file)
                return
            end

            tform = app.TForm;
            save(fullfile(location,file), 'tform','-mat');
        end

        % Button pushed function: CalibShow
        function CalibShowButtonPushed(app, event)
            J = imwarp(app.Image,app.TForm);
            figure; imshow(flipud(J));
        end

        % Button pushed function: CalibCalculate
        function CalibCalculateButtonPushed(app, event)
            xlen = app.CalibXLen.Value;
            ylen = app.CalibYLen.Value;

            % unit vectors
            switch app.CalibXDir.Value
                case 'AB'
                    x0 = [1   0  0];
                case 'BA'
                    x0 = [-1  0  0];
                case 'AD'
                    x0 = [0   1  0];
                case 'DA'
                    x0 = [0  -1  0];
            end
            switch app.CalibYDir.Value
                case 'AB'
                    y0 = [1   0  0];
                case 'BA'
                    y0 = [-1  0  0];
                case 'AD'
                    y0 = [0   1  0];
                case 'DA'
                    y0 = [0  -1  0];
            end
            z0 = cross(x0,y0);

            if abs(z0) == 0
                warning('Invalid combination of x- and y-direction.')
                return
            end

            % A->B->C->D 
            M = [x0;y0;z0];
            P = [[0 0 0]; [1 0 0]; [1 1 0]; [0 1 0]]';

            D = (M*P)';
            D = D(1:4,1:2);

            % adjust origin 
            switch app.CalibOrigin.Value
                case 'A'
                    origin = 1;
                case 'AB'
                    origin = [1 2];
                case 'B'
                    origin = 2;
                case 'BC'
                    origin = [2 3];
                case 'C'
                    origin = 3;
                case 'CD'
                    origin = [3 4];
                case 'D'
                    origin = 4;
                case 'DA'
                    origin = [4 1];
            end
            
            D = D - sum(D(origin,:),1)/numel(origin);

            % calculate transform
            fixedPoints = D .* [xlen ylen];
            movingPoints = [app.Points.XData(1:4);app.Points.YData(1:4)]';

            tform = fitgeotform2d(movingPoints,fixedPoints,"projective");
            app.TForm = tform;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageAxes = uiaxes(app.GridLayout);
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            zlabel(app.ImageAxes, 'Z')
            app.VideoPanel = uipanel(app.GridLayout);
            app.VideoPanel.Title = 'Video';
            app.VideoPanelGrid = uigridlayout(app.VideoPanel);
            app.VideoOpenButton = uibutton(app.VideoPanelGrid, 'push');
            app.VideoOpenButton.ButtonPushedFcn = createCallbackFcn(app, @VideoOpenButtonPushed, true);
            app.VideoOpenButton.Text = 'Open';
            app.VideoFileLabel = uilabel(app.VideoPanelGrid);
            app.VideoFileLabel.Text = '(no file selected)';
            app.WidthEditFieldLabel = uilabel(app.VideoPanelGrid);
            app.WidthEditFieldLabel.Text = 'Width:';
            app.VideoWidthField = uieditfield(app.VideoPanelGrid, 'numeric');
            app.VideoWidthField.Editable = 'off';
            app.HeightEditFieldLabel = uilabel(app.VideoPanelGrid);
            app.HeightEditFieldLabel.Text = 'Height:';
            app.VideoHeightField = uieditfield(app.VideoPanelGrid, 'numeric');
            app.VideoHeightField.Editable = 'off';
            app.FPSEditFieldLabel = uilabel(app.VideoPanelGrid);
            app.FPSEditFieldLabel.Text = 'FPS:';
            app.VideoFPSField = uieditfield(app.VideoPanelGrid, 'numeric');
            app.VideoFPSField.Editable = 'off';
            app.CalibPanel = uipanel(app.GridLayout);
            app.CalibPanel.Title = 'Calibration';
            app.CalibPanelGrid = uigridlayout(app.CalibPanel);
            app.TypeDropDownLabel = uilabel(app.CalibPanelGrid);
            app.TypeDropDownLabel.Text = 'Type:';
            app.CalibType = uidropdown(app.CalibPanelGrid);
            app.CalibType.Items = {'projective'};
            app.CalibType.ValueChangedFcn = createCallbackFcn(app, @CalibTypeValueChanged, true);
            app.CalibType.Value = 'projective';
            app.CalibInitButton = uibutton(app.CalibPanelGrid, 'push');
            app.CalibInitButton.ButtonPushedFcn = createCallbackFcn(app, @CalibInitButtonPushed, true);
            app.CalibInitButton.Text = 'Initialize';
            app.OriginLabel = uilabel(app.CalibPanelGrid);
            app.OriginLabel.Text = 'Origin:';
            app.CalibOrigin = uidropdown(app.CalibPanelGrid);
            app.CalibOrigin.Items = {'A', 'AB', 'B', 'BC', 'C', 'CD', 'D', 'DA'};
            app.CalibOrigin.Value = 'A';
            app.XdirectionDropDownLabel = uilabel(app.CalibPanelGrid);
            app.XdirectionDropDownLabel.Text = 'X direction:';
            app.CalibXDir = uidropdown(app.CalibPanelGrid);
            app.CalibXDir.Items = {'AB', 'BA', 'AD', 'DA'};
            app.CalibXDir.Value = 'DA';
            app.YdirectionDropDownLabel = uilabel(app.CalibPanelGrid);
            app.YdirectionDropDownLabel.Text = 'Y direction:';
            app.CalibYDir = uidropdown(app.CalibPanelGrid);
            app.CalibYDir.Items = {'AB', 'BA', 'AD', 'DA'};
            app.CalibYDir.Value = 'BA';
            app.XlengthmmEditFieldLabel = uilabel(app.CalibPanelGrid);
            app.XlengthmmEditFieldLabel.Text = 'X length (mm):';
            app.CalibXLen = uieditfield(app.CalibPanelGrid, 'numeric');
            app.CalibXLen.Value = 1000;
            app.YlengthmmEditFieldLabel = uilabel(app.CalibPanelGrid);
            app.YlengthmmEditFieldLabel.Text = 'Y length (mm):';
            app.CalibYLen = uieditfield(app.CalibPanelGrid, 'numeric');
            app.CalibYLen.Value = 500;
            app.CalibSave = uibutton(app.CalibPanelGrid, 'push');
            app.CalibSave.ButtonPushedFcn = createCallbackFcn(app, @CalibSaveButtonPushed, true);
            app.CalibSave.Text = 'Save...';
            app.CalibShow = uibutton(app.CalibPanelGrid, 'push');
            app.CalibShow.ButtonPushedFcn = createCallbackFcn(app, @CalibShowButtonPushed, true);
            app.CalibShow.Text = 'Show...';
            app.CalibCalculate = uibutton(app.CalibPanelGrid, 'push');
            app.CalibCalculate.ButtonPushedFcn = createCallbackFcn(app, @CalibCalculateButtonPushed, true);
            app.CalibCalculate.Text = 'Calculate';
            app.Slider = uislider(app.GridLayout);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end