classdef general < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout              matlab.ui.container.GridLayout
        SearchpathActionGrid    matlab.ui.container.GridLayout
        SearchpathActionRemove  matlab.ui.control.Button
        SearchpathActionAdd     matlab.ui.control.Button
        ScratchGrid             matlab.ui.container.GridLayout
        ScratchPathEdit         matlab.ui.control.EditField
        ScratchPathButton       matlab.ui.control.Button
        ScratchHelp             matlab.ui.control.Button
        ScratchLabel            matlab.ui.control.Label
        CacheGrid               matlab.ui.container.GridLayout
        CacheType               matlab.ui.control.DropDown
        CacheSizeEdit           matlab.ui.control.EditField
        MaxsizeLabel            matlab.ui.control.Label
        CachePathEdit           matlab.ui.control.EditField
        CachPathButton          matlab.ui.control.Button
        CacheHelp               matlab.ui.control.Button
        CacheLabel              matlab.ui.control.Label
        SearchpathHelp          matlab.ui.control.Button
        SearchpathLabel         matlab.ui.control.Label
        SearchpathTable         matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % set column types in search-path table
            Location = {'/home/sawinski'}; 
            Remote = [false]; 
            Removable = [false];
            comp.SearchpathTable.Data = table(Location,Remote,Removable);
        end

        % Button pushed function: ScratchPathButton
        function ScratchPathButtonPushed(comp, event)
            p = uigetdir(comp.ScratchPathEdit.Value);
            if isnumeric(p)
                return
            end
            comp.ScratchPathEdit.Value = p;
            comp.ScratchPathEditValueChanged([]);
        end

        % Value changed function: ScratchPathEdit
        function ScratchPathEditValueChanged(comp, event)
            value = comp.ScratchPathEdit.Value;
            % FIXME
        end

        % Button pushed function: CachPathButton
        function CachPathButtonPushed(comp, event)
            p = uigetdir(comp.CachePathEdit.Value);
            if isnumeric(p)
                return
            end
            comp.CachePathEdit.Value = p;
            comp.CachePathEditValueChanged([]);            
        end

        % Value changed function: CachePathEdit
        function CachePathEditValueChanged(comp, event)
            value = comp.CachePathEdit.Value;
            % FIXME
        end

        % Value changed function: CacheSizeEdit
        function CacheSizeEditValueChanged(comp, event)
            value = comp.CacheSizeEdit.Value;
            value = regexp(value, "(?<num>[1-9][0-9]*([.][0-9]*)?)[ ]?(?<unit>.*)",'names');

            if isempty(value)
                % FIXME reset
                return
            end

            % handle unit
            unit = value.unit;
            if isempty(unit)
                factor = 3; % default is GiB
            else
                switch(lower(unit))
                    case {'k','kb','kib'}
                        factor = 1;
                    case {'m','mb','mib'}
                        factor = 2;
                    case {'g','gb','gib'}
                        factor = 3;
                    case {'t','tb','tib'}
                        factor = 4;
                    case {'p','pb','pib'}
                        factor = 5;
                end
            end

            % handle number
            num = str2double(value.num);
            while (factor < 5) && (num > 1024)
                factor = factor + 1;
                num = num/1024;
            end
           
            % update text
            if factor == 0
                % FIXME warning, this maketh no sense
                comp.CacheSizeEdit.Value = sprintf("%g", num);
            else
                units = ['k','M','G','T','P'];
                comp.CacheSizeEdit.Value = sprintf("%g%siB", num, units(factor));
            end

            % update size
            sizeGiB = int64(num * 1024^(factor-3));
            % FIXME            
        end

        % Cell edit callback: SearchpathTable
        function SearchpathTableCellEdit(comp, event)
            row = event.Indices(1);
            % FIXME
        end

        % Button pushed function: SearchpathActionRemove
        function SearchpathActionRemoveButtonPushed(comp, event)
            sel = comp.SearchpathTable.Selection;
            if ~isscalar(sel)
                return
            end
            data = comp.SearchpathTable.Data;
            comp.SearchpathTable.Data = [data(1:sel-1,:);data(sel+1:end,:)];
            comp.SearchpathTable.Selection = [];
        end

        % Button pushed function: SearchpathActionAdd
        function SearchpathActionAddButtonPushed(comp, event)
            sel = comp.SearchpathTable.Selection;
            data = comp.SearchpathTable.Data;
            if ~isempty(sel)
                sel = max(sel) + 1;
            else
                sel = height(data) + 1;
            end

            data = [data(1:sel-1,:);{pwd,false,false};data(sel:end,:)];
            comp.SearchpathTable.Data = data;
            comp.SearchpathTable.Selection = sel;

            comp.SearchpathTableDoubleClicked(struct(InteractionInformation=struct(DisplayRow=sel)));
        end

        % Double-clicked callback: SearchpathTable
        function SearchpathTableDoubleClicked(comp, event)
            displayRow = event.InteractionInformation.DisplayRow;
            data = comp.SearchpathTable.Data;

            p = uigetdir(data.Location{displayRow});
            if isnumeric(p)
                return
            end
            
            data.Location{displayRow} = p;
            comp.SearchpathTable.Data = data;

            comp.SearchpathTableCellEdit(struct(Indices=[displayRow 1]));
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 550 600];
            comp.GridLayout = uigridlayout(comp);
            comp.SearchpathTable = uitable(comp.GridLayout);
            comp.SearchpathTable.ColumnName = {'Location'; 'Type'};
            comp.SearchpathTable.RowName = {};
            comp.SearchpathTable.SelectionType = 'row';
            comp.SearchpathTable.ColumnEditable = [true true];
            comp.SearchpathTable.CellEditCallback = matlab.apps.createCallbackFcn(comp, @SearchpathTableCellEdit, true);
            comp.SearchpathTable.DoubleClickedFcn = matlab.apps.createCallbackFcn(comp, @SearchpathTableDoubleClicked, true);
            comp.SearchpathLabel = uilabel(comp.GridLayout);
            comp.SearchpathLabel.Text = 'Search paths:';
            comp.SearchpathHelp = uibutton(comp.GridLayout, 'push');
            comp.SearchpathHelp.Text = '?';
            comp.CacheLabel = uilabel(comp.GridLayout);
            comp.CacheLabel.Text = 'Cache directory and size:';
            comp.CacheHelp = uibutton(comp.GridLayout, 'push');
            comp.CacheHelp.Text = '?';
            comp.CacheGrid = uigridlayout(comp.GridLayout);
            comp.CachPathButton = uibutton(comp.CacheGrid, 'push');
            comp.CachPathButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @CachPathButtonPushed, true);
            comp.CachPathButton.Text = '>';
            comp.CachePathEdit = uieditfield(comp.CacheGrid, 'text');
            comp.CachePathEdit.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CachePathEditValueChanged, true);
            comp.CachePathEdit.Value = '${HOME}/.cache/ana';
            comp.MaxsizeLabel = uilabel(comp.CacheGrid);
            comp.MaxsizeLabel.Text = 'Max. size:';
            comp.CacheSizeEdit = uieditfield(comp.CacheGrid, 'text');
            comp.CacheSizeEdit.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CacheSizeEditValueChanged, true);
            comp.CacheSizeEdit.Value = '1TiB';
            comp.CacheType = uidropdown(comp.CacheGrid);
            comp.CacheType.Items = {'Local', 'Remote', 'Removable'};
            comp.CacheType.Value = 'Local';
            comp.ScratchLabel = uilabel(comp.GridLayout);
            comp.ScratchLabel.Text = 'Scratch directory:';
            comp.ScratchHelp = uibutton(comp.GridLayout, 'push');
            comp.ScratchHelp.Text = '?';
            comp.ScratchGrid = uigridlayout(comp.GridLayout);
            comp.ScratchPathButton = uibutton(comp.ScratchGrid, 'push');
            comp.ScratchPathButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ScratchPathButtonPushed, true);
            comp.ScratchPathButton.Text = '>';
            comp.ScratchPathEdit = uieditfield(comp.ScratchGrid, 'text');
            comp.ScratchPathEdit.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ScratchPathEditValueChanged, true);
            comp.ScratchPathEdit.Value = '${HOME}/.cache/ana';
            comp.SearchpathActionGrid = uigridlayout(comp.GridLayout);
            comp.SearchpathActionAdd = uibutton(comp.SearchpathActionGrid, 'push');
            comp.SearchpathActionAdd.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SearchpathActionAddButtonPushed, true);
            comp.SearchpathActionAdd.Text = 'Add';
            comp.SearchpathActionRemove = uibutton(comp.SearchpathActionGrid, 'push');
            comp.SearchpathActionRemove.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SearchpathActionRemoveButtonPushed, true);
            comp.SearchpathActionRemove.Text = 'Remove';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end