classdef TimeBar < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        Draw  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        ButtonTrack (1,1) logical = false; % Flag, if tracking mouse button/motion
        Figure % Root figure handle
        TimeHit (1,1) % Button press time
        WindowButtonDownTic % Result of 'tic()' at button press
        WindowButtonMotionFcn % Backup callback
        WindowButtonUpFcn % Backup callback
    end
    
    properties (Access = protected)
        Time (1,2) = [0 1]; % Limits for x-axis (time).
    end
    
    
    methods (Hidden)

    end

    methods (Access = public)
        
        function results = setTime(comp,t)
            %SETTIME    Set limits of the time axis.
            %
            % 
            comp.Time = t;
            set(comp.Draw,XLim = t);
        end

    end
    
    methods (Access = private)

        function MouseButtonMotion(comp,~,~)
            if ~comp.ButtonTrack
                return
            end

            curtime = comp.Draw.CurrentPoint(1,1);
            if curtime == comp.TimeHit
                return
            end

            try
                disp(curtime)
            catch
            end
        end

        function MouseButtonUp(comp,~,~)
            if ~comp.ButtonTrack
                return
            end

            % reset callbacks
            comp.Figure.WindowButtonMotionFcn = comp.WindowButtonMotionFcn;
            comp.Figure.WindowButtonUpFcn = comp.WindowButtonUpFcn;
            comp.ButtonTrack = false;

            try
                dt = toc(comp.WindowButtonDownTic); % time between down and up
                onlyclick = dt <= 0.2;
                motion = comp.Draw.CurrentPoint(1,1) == comp.TimeHit;

            catch
            end
        end

        function MouseButtonDown(comp,~,~)
            try
                switch(comp.Figure.SelectionType)
                    case 'normal' % left
                        % assign mouse callbacks, save time "hit"
                        comp.WindowButtonMotionFcn = comp.Figure.WindowButtonMotionFcn;
                        comp.WindowButtonUpFcn = comp.Figure.WindowButtonUpFcn;

                        comp.Figure.WindowButtonMotionFcn = @(f, e) comp.MouseButtonMotion(f, e);
                        comp.Figure.WindowButtonUpFcn = @(f, e) comp.MouseButtonUp(f, e);

                        comp.WindowButtonDownTic = tic();

                        cp = comp.Draw.CurrentPoint;
                        comp.TimeHit = cp(1,1);

                    case 'alt' % right
                        return

                    case 'extend' % middle
                        return
                end
            catch
            end

            comp.ButtonTrack = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.Figure = ancestor(comp,'figure');
            axis(comp.Draw,'tight');
            % comp.Draw.ButtonDownFcn = @(src,ev) comp.MouseButtonDown(src,ev);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            comp.Draw.Position = [1 1 comp.Position(3:4)];
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 800 200];
            comp.Draw = uiaxes(comp);
            comp.Draw.XTick = [];
            comp.Draw.YTick = [];
            comp.Draw.ZTick = [];
            comp.Draw.Toolbar = [];
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end