classdef VideoPlayer < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout       matlab.ui.container.GridLayout
        Status           matlab.ui.control.Label
        Filters          matlab.ui.container.TabGroup
        InfoTab          matlab.ui.container.Tab
        InfoGrid         matlab.ui.container.GridLayout
        ROIShow          matlab.ui.control.CheckBox
        ROIDelete        matlab.ui.control.Button
        ROIAdd           matlab.ui.control.Button
        ROILoadFromFile  matlab.ui.control.Button
        ROILabel         matlab.ui.control.Label
        ROITable         matlab.ui.control.Table
        Controls         matlab.ui.container.Panel
        ControlsGrid     matlab.ui.container.GridLayout
        Count            matlab.ui.control.EditField
        Repeat           matlab.ui.control.CheckBox
        ButtonNext       matlab.ui.control.Button
        ButtonStop       matlab.ui.control.Button
        ButtonPrevious   matlab.ui.control.Button
        ButtonPlay       matlab.ui.control.Button
        Frame            matlab.ui.control.EditField
        FrameTotal       matlab.ui.control.Label
        Time             matlab.ui.control.EditField
        Slider           matlab.ui.control.UIAxes
        Image            matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Directory = []; % Image sequence directory
        Store = []; % Image data store.
    end
    
    
    methods (Access = public)
        function showControls(comp, visible)
            %showControls   Show (or hide) controls.
            arguments
                comp
                visible = true
            end

            if visible
                comp.GridLayout.RowHeight{3} = "fit";
            else
                comp.GridLayout.RowHeight{3} = 0;
            end
        end

        function showFilters(comp, visible)
            %showControls   Show (or hide) controls.
            arguments
                comp
                visible = true
            end

            if visible
                comp.GridLayout.ColumnWidth{3} = "fit";
            else
                comp.GridLayout.ColumnWidth{3} = 0;
            end
        end

        function res = currentDirectory(comp)
            %currentDirectory   Get current image datastore directory.
            if isempty(comp.Directory)
                cfgfile = ana.os.paths('configdir') / "ui" / "ana.ui.video.yaml";
                if cfgfile.isfile()
                    cfg = ana.file.yaml.load(cfgfile);
                    comp.Directory = cfg.last_visited.directory;
                else
                    comp.Directory = pwd;
                end
            end
            res = comp.Directory;
        end
    
        function setDirectory(comp,seqdir)
            %setDirectory   Set image datastore directory.
            %
            

            % save config 
            cfgdir = ana.os.paths('configdir') / "ui";
            if ~cfgdir.isfolder()
                mkdir(string(cfgdir));
            end

            cfgfile = ana.os.paths('configdir') / "ui" / "ana.ui.video.yaml";

            cfg = struct("last_visited",[]);
            cfg.last_visited.directory = string(seqdir);

            ana.file.yaml.save(cfgfile,cfg);
        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
           axis(comp.Slider,'tight');
           axis(comp.Image,'tight');
           imshow(imread('office_2.jpg'),Parent=comp.Image);
        end

        % Callback function
        function ToggleRepeatValueChanged(comp, event)
            % value = comp.ToggleRepeat.Value;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1024 768];
            comp.GridLayout = uigridlayout(comp);
            comp.Image = uiaxes(comp.GridLayout);
            comp.Image.XTick = [];
            comp.Image.YTick = [];
            comp.Image.Visible = 'off';
            comp.Controls = uipanel(comp.GridLayout);
            comp.ControlsGrid = uigridlayout(comp.Controls);
            comp.Slider = uiaxes(comp.ControlsGrid);
            comp.Slider.XTick = [];
            comp.Slider.YTick = [];
            comp.Slider.ZTick = [];
            comp.Time = uieditfield(comp.ControlsGrid, 'text');
            comp.Time.Value = '--:--:--.---';
            comp.FrameTotal = uilabel(comp.ControlsGrid);
            comp.FrameTotal.Text = '/-----';
            comp.Frame = uieditfield(comp.ControlsGrid, 'text');
            comp.Frame.Value = '----';
            comp.ButtonPlay = uibutton(comp.ControlsGrid, 'push');
            comp.ButtonPlay.Text = '▶️';
            comp.ButtonPrevious = uibutton(comp.ControlsGrid, 'push');
            comp.ButtonPrevious.Text = '⏮️';
            comp.ButtonStop = uibutton(comp.ControlsGrid, 'push');
            comp.ButtonStop.Text = '⏹️';
            comp.ButtonNext = uibutton(comp.ControlsGrid, 'push');
            comp.ButtonNext.Text = '⏭️';
            comp.Repeat = uicheckbox(comp.ControlsGrid);
            comp.Repeat.Text = '🔁︎';
            comp.Repeat.Value = true;
            comp.Count = uieditfield(comp.ControlsGrid, 'text');
            comp.Count.CharacterLimits = [1 1000];
            comp.Count.Value = '1';
            comp.Filters = uitabgroup(comp.GridLayout);
            comp.InfoTab = uitab(comp.Filters);
            comp.InfoTab.Title = 'Info';
            comp.InfoGrid = uigridlayout(comp.InfoTab);
            comp.ROITable = uitable(comp.InfoGrid);
            comp.ROITable.ColumnName = {'Name'; 'Mask'};
            comp.ROITable.RowName = {};
            comp.ROILabel = uilabel(comp.InfoGrid);
            comp.ROILabel.Text = 'ROIs:';
            comp.ROILoadFromFile = uibutton(comp.InfoGrid, 'push');
            comp.ROILoadFromFile.Text = 'Load...';
            comp.ROIAdd = uibutton(comp.InfoGrid, 'push');
            comp.ROIAdd.Text = '+';
            comp.ROIDelete = uibutton(comp.InfoGrid, 'push');
            comp.ROIDelete.Text = '–';
            comp.ROIShow = uicheckbox(comp.InfoGrid);
            comp.ROIShow.Text = 'Show';
            comp.Status = uilabel(comp.GridLayout);
            comp.Status.Text = 'No video loaded...';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end