classdef Configuration < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        Area  matlab.ui.container.Panel
        Tree  matlab.ui.container.Tree
    end

    properties (Access = private)
        Pages % Node entries with description and pages.
    end
    
    
    methods (Access = public)
        
        function apply(comp)
            %APPLY  Apply configuration changes.
            arguments
                comp ana.ui.Configuration
            end
            % FIXME
        end
        
        function reset(comp)
            %RESET  Reset configuration changes.
            arguments
                comp ana.ui.Configuration
            end
            % FIXME
        end
        
        function add(comp,key,name,ui)
            %ADD    Add a (hierarchical) settings page.
            arguments
                comp ana.ui.Configuration
                key {mustBeTextScalar}
                name {mustBeTextScalar}
                ui = []
            end
            
        end
    end
    

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 800 600];
            comp.Tree = uitree(comp);
            comp.Area = uipanel(comp);
        end
    end
end