classdef statusbar < matlab.ui.componentcontainer.ComponentContainer
    %STATUSBAR Status bar component with text and progress.
    %   FIXME

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        Text        matlab.ui.control.Label
        Axes        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Message = {}; % Message stack.
        Progress matlab.graphics.primitive.Rectangle % Progress bar rectangle.
    end
    
    
    methods (Access = public)
        function push(comp, message)
            %push   Push a status-bar message.
            comp.Text.Text = message;
            comp.Message = [comp.Message,{message}];
        end

        function pop(comp)
            %pop    Pop a status-bar message.
            comp.Message = comp.Message(1:end-1);
            if isempty(comp.Message)
                comp.Text.Text = "";
            else
                comp.Text.Text = comp.Message{end};
            end
        end

        function clear(comp)
            %clear  Clear status bar.
            comp.Text.Text = "";
            comp.Message = {};
        end

        function progress(comp, frac)
            %progress    Set progress value [0,1].
            comp.Progress.Position(3) = min(1,max(frac,eps));
            drawnow();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.Axes.XAxis.Visible = 'off';
            comp.Axes.YAxis.Visible = 'off';
            axis(comp.Axes, [0 1 0 1]);
            comp.Progress = rectangle(comp.Axes, 'Position', [0 0 eps 1], ...
                'Edgecolor', 'none', 'Facecolor', 'b');

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 565 36];
            comp.GridLayout = uigridlayout(comp);
            comp.Axes = uiaxes(comp.GridLayout);
            comp.Axes.XTick = [];
            comp.Axes.YTick = [];
            comp.Axes.ZTick = [];
            comp.Text = uilabel(comp.GridLayout);
            comp.Text.Text = '';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end